classdef President < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Button_26                     matlab.ui.control.StateButton
        Button_25                     matlab.ui.control.StateButton
        Button_24                     matlab.ui.control.StateButton
        Button_23                     matlab.ui.control.StateButton
        Button_22                     matlab.ui.control.StateButton
        Button_21                     matlab.ui.control.StateButton
        Button_20                     matlab.ui.control.StateButton
        Button_19                     matlab.ui.control.StateButton
        Button_18                     matlab.ui.control.StateButton
        Button_17                     matlab.ui.control.StateButton
        Button_16                     matlab.ui.control.StateButton
        Button_15                     matlab.ui.control.StateButton
        Button_14                     matlab.ui.control.StateButton
        ConfirmButton                 matlab.ui.control.Button
        HowManyPlayersEditField       matlab.ui.control.NumericEditField
        HowManyPlayersEditFieldLabel  matlab.ui.control.Label
        HomeButton                    matlab.ui.control.Button
        JoinGameButton                matlab.ui.control.Button
        PlayButton                    matlab.ui.control.Button
        RulesButton                   matlab.ui.control.Button
        Player3Label                  matlab.ui.control.Label
        Player1Label                  matlab.ui.control.Label
        Player2Label                  matlab.ui.control.Label
        Player4Label                  matlab.ui.control.Label
        LastHandPlayedxLabel          matlab.ui.control.Label
        WhosUpPlLabel                 matlab.ui.control.Label
        PlayCardsButton               matlab.ui.control.Button
        SkipTurnButton                matlab.ui.control.Button
        Image6                        matlab.ui.control.Image
        Image2_3                      matlab.ui.control.Image
        Image2_5                      matlab.ui.control.Image
        Image2_4                      matlab.ui.control.Image
        Image2_2                      matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
    end

    
    properties (Access = public)
        cardNum              % Array of all 52 Cards
        cardDeck            % Array of Card objects
        numPlayers          % number of player
        playerTurn          % Current Player
        gameState           % Is the round over?
        winPlayer = 0       % Who won the round
        playerWins          % Number of wins
        playerDecks         % MxN array with M = # of players & N = number of cards
        playedCards         % Cards on the table
        selectedCards       % Cards selected to be played

        playedSetSize       % Size of played set of cards (double, triple, etc.)
        selectedSetSize     % Size of selected set of cards (double, triple, etc.)
        selectedCardValue   % Card numbers converted to values
        playedCardValue     % Card numbers converted to values

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        % drawnow;
            app.UIFigure.WindowState = 'maximized';
            app.RulesButton.Visible = "on";
            app.Player1Label.Visible = "off";
            app.Player2Label.Visible = "off";
            app.Player3Label.Visible = "off";
            app.Player4Label.Visible = "off";
            app.LastHandPlayedxLabel.Visible = "off";
            app.WhosUpPlLabel.Visible = "off";
            app.PlayCardsButton.Visible = "off";
            app.SkipTurnButton.Visible = "off";
            app.Image6.Visible = "off";
            app.Image2_5.Visible = "off";
            app.Image2_4.Visible = "off";
            app.Image2_3.Visible = "on";
            app.Image2_2.Visible = "off";
            app.Image2.Visible = "off";
            app.HomeButton.Visible = "off";
            app.HowManyPlayersEditField.Visible= "off";
            app.HowManyPlayersEditFieldLabel.Visible = "off";
            app.ConfirmButton.Visible ="off";
            app.Button_1.Visible="off";
            app.Button_2.Visible="off";
            app.Button_3.Visible="off";
            app.Button_4.Visible="off";
            app.Button_5.Visible="off";
            app.Button_6.Visible="off";
            app.Button_7.Visible="off";
            app.Button_8.Visible="off";
            app.Button_9.Visible="off";
            app.Button_10.Visible="off";
            app.Button_11.Visible="off";
            app.Button_12.Visible="off";
            app.Button_13.Visible="off";
            

        end

        % Button pushed function: RulesButton
        function RulesButtonPushed(app, event)
            RulesPopup
            Card_dealt = 'victory.mp3';
            audiowrite(Card_dealt,y,Fs);
            clear y Fs app
            [y,Fs] = audioread('victory.mp3');
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
                              drawnow;
            app.UIFigure.WindowState = 'maximized';
            app.RulesButton.Visible = "on";
            app.Player1Label.Visible = "off";
            app.Player2Label.Visible = "off";
            app.Player3Label.Visible = "off";
            app.Player4Label.Visible = "off";
            app.LastHandPlayedxLabel.Visible = "off";
            app.WhosUpPlLabel.Visible = "off";
            app.PlayCardsButton.Visible = "off";
            app.SkipTurnButton.Visible = "off";
            app.Image6.Visible = "off";
            app.Image2_5.Visible = "off";
            app.Image2_4.Visible = "off";
            app.Image2_3.Visible = "on"; %% keep president image up 
            app.Image2_2.Visible = "off";
            app.Image2.Visible = "off";
            app.PlayButton.Visible = "off";
            app.JoinGameButton.Visible = "off";
            app.HowManyPlayersEditField.Visible ="on";
            app.HowManyPlayersEditFieldLabel.Visible = "on";
            app.ConfirmButton.Visible ="on";

        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.numPlayers = app.HowManyPlayersEditField.Value;
            app.Button_1.Visible="on";
            app.Button_2.Visible="on";
            app.Button_3.Visible="on";
            app.Button_4.Visible="on";
            app.Button_5.Visible="on";
            app.Button_6.Visible="on";
            app.Button_7.Visible="on";
            app.Button_8.Visible="on";
            app.Button_9.Visible="on";
            app.Button_10.Visible="on";
            app.Button_11.Visible="on";
            app.Button_12.Visible="on";
            app.Button_13.Visible="on";
            %How many players are there?
            if (app.numPlayers >= 2) && (app.numPlayers <= 4)

                %% if two players are selected
                if app.HowManyPlayersEditField.Value == 2
                    app.RulesButton.Visible = "on";
                    app.Player1Label.Visible = "on";
                    app.Player2Label.Visible = "on";
                    app.Player3Label.Visible = "off";
                    app.Player4Label.Visible = "off";
                    app.LastHandPlayedxLabel.Visible = "on";
                    app.WhosUpPlLabel.Visible = "on";
                    app.PlayCardsButton.Visible = "on";
                    app.SkipTurnButton.Visible = "on";
                    app.Image6.Visible = "on";
                    app.Image2_5.Visible = "off";
                    app.Image2_4.Visible = "on";
                    app.Image2_3.Visible = "on"; %% keep president image up 
                    app.Image2_2.Visible = "off";
                    app.Image2.Visible = "on";
                    app.PlayButton.Visible = "off";
                    app.JoinGameButton.Visible = "off";
                    app.HowManyPlayersEditField.Visible ="off";
                    app.HowManyPlayersEditFieldLabel.Visible = "off";
                    app.ConfirmButton.Visible ="off";
                end

                if app.HowManyPlayersEditField.Value == 3
                    app.RulesButton.Visible = "on";
                    app.Player1Label.Visible = "on";
                    app.Player2Label.Visible = "on";
                    app.Player3Label.Visible = "on";
                    app.Player4Label.Visible = "off";
                    app.LastHandPlayedxLabel.Visible = "on";
                    app.WhosUpPlLabel.Visible = "on";
                    app.PlayCardsButton.Visible = "on";
                    app.SkipTurnButton.Visible = "on";
                    app.Image6.Visible = "on";
                    app.Image2_5.Visible = "on";
                    app.Image2_4.Visible = "on";
                    app.Image2_3.Visible = "on"; %% keep president image up 
                    app.Image2_2.Visible = "off";
                    app.Image2.Visible = "on";
                    app.PlayButton.Visible = "off";
                    app.JoinGameButton.Visible = "off";
                    app.HowManyPlayersEditField.Visible ="off";
                    app.HowManyPlayersEditFieldLabel.Visible = "off";
                    app.ConfirmButton.Visible ="off";
                end

                %% if 4 players are selected
                if app.HowManyPlayersEditField.Value == 4
                    app.RulesButton.Visible = "on";
                    app.Player1Label.Visible = "on";
                    app.Player2Label.Visible = "on";
                    app.Player3Label.Visible = "on";
                    app.Player4Label.Visible = "on";
                    app.LastHandPlayedxLabel.Visible = "on";
                    app.WhosUpPlLabel.Visible = "on";
                    app.PlayCardsButton.Visible = "on";
                    app.SkipTurnButton.Visible = "on";
                    app.Image6.Visible = "on";
                    app.Image2_5.Visible = "on";
                    app.Image2_4.Visible = "on";
                    app.Image2_3.Visible = "on"; %% keep president image up 
                    app.Image2_2.Visible = "on";
                    app.Image2.Visible = "on";
                    app.PlayButton.Visible = "off";
                    app.JoinGameButton.Visible = "off";
                    app.HowManyPlayersEditField.Visible ="off";
                    app.HowManyPlayersEditFieldLabel.Visible = "off";
                    app.ConfirmButton.Visible ="off";
                end
                
                %Create random order of 52 unique cards
                app.cardNum = randperm(52);
                app.cardDeck = Card.empty;
                app.playerDecks = Card.empty;

                for i = app.cardNum
                    number = i;
                    image = i + ".png";
                    app.cardDeck = cat(2,app.cardDeck,Card(number,image));
                end 
            
                fprintf("Deck generated \n")

                %app.playerDecks = zeros(app.numPlayers, 13);
                %temp = 
                for i = 1:app.numPlayers
                    %app.playerDecks(i, :) = app.cardDeck((13*i - 12): 13*i);
                    temp = app.cardDeck((13*i - 12): 13*i);
                    app.playerDecks = cat(1, app.playerDecks, temp(i));
                    fprintf("A deck as been created! \n")
                    disp(app.playerDecks)
                end
            else
                fprintf("Number of players not supported \n")
            end
            
        end

        % Button pushed function: PlayCardsButton
        function PlayCardsButtonPushed(app, event)
            app.playedSetSize = length(app.playedCards);
            app.playedCardValue = mod(app.playedCards, 13);

            app.selectedSetSize = length(app.selectedCards);
            app.selectedCardValue = mod(app.selectedCards, 13);
            
            if isempty(app.selectedCards)
                fprintf("Please select at least one card \n")
                return
            end

            %Check if cards played are of matching value
            if app.selectedSetSize > 1
                for i = 1:(app.selectedSetSize - 1)
                    if not(app.selectedCardValue(i) == app.selectedCardValue(i+1))
                        fprintf("Cards are not of matching value \n")
                        return
                    end
                end
            end
        
            %Check if first player
            if isempty(app.playedCards)
                app.playedCards = app.selectedCards;
                fprintf("Cards played sucessfully! \n")
                return
        
            end
            
            %Check if number of cards played matches previous play
            if not(app.selectedSetSize == app.playedSetSize)
               fprintf("Does not match set size \n")
               return
            end
            
            %Check if cards played are higher value
            if app.selectedCardValue(1) > app.playedCardValue(1)
                app.playedCards = app.selectedCards;
                fprintf("Cards played sucessfully! \n")

                for i = 1:app.numPlayers
                    playersHand = app.playerDecks(i,:); %isolates one player's hand
                    summedHand = sum(playersHand); %sums all the values of that players hand

                    if summedHand == 0  %Checks if hand sums to 0, meaning the hand is empty
                        app.gameState = "Round Complete";
                        app.winPlayer = i;
                        break
                    else 
                        app.gameState = "Round Incomplete";
                        app.winPlayer = 0 ;
                    end 
                end 
            else
                fprintf("Value of cards not high enough \n")
            end
        end

        % Button pushed function: SkipTurnButton
        function SkipTurnButtonPushed(app, event)

        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.RulesButton.Visible = "on";
            app.Player1Label.Visible = "off";
            app.Player2Label.Visible = "off";
            app.Player3Label.Visible = "off";
            app.Player4Label.Visible = "off";
            app.LastHandPlayedxLabel.Visible = "off";
            app.WhosUpPlLabel.Visible = "off";
            app.PlayCardsButton.Visible = "off";
            app.SkipTurnButton.Visible = "off";
            app.Image6.Visible = "off";
            app.Image2_5.Visible = "off";
            app.Image2_4.Visible = "off";
            app.Image2_3.Visible = "on";
            app.Image2_2.Visible = "off";
            app.Image2.Visible = "off";
            app.HomeButton.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background.png');
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background.png');
            app.Image2_4 = uiimage(app.UIFigure);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background.png');
            app.Image2_5 = uiimage(app.UIFigure);
            app.Image2_5.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background.png');
            app.Image2_3 = uiimage(app.UIFigure);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'President Title.png');
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background bigger.png');
            app.SkipTurnButton = uibutton(app.UIFigure, 'push');
            app.SkipTurnButton.ButtonPushedFcn = createCallbackFcn(app, @SkipTurnButtonPushed, true);
            app.SkipTurnButton.Text = 'Skip Turn';
            app.PlayCardsButton = uibutton(app.UIFigure, 'push');
            app.PlayCardsButton.ButtonPushedFcn = createCallbackFcn(app, @PlayCardsButtonPushed, true);
            app.PlayCardsButton.Text = 'Play Cards';
            app.WhosUpPlLabel = uilabel(app.UIFigure);
            app.WhosUpPlLabel.Text = {'Who''s Up:'; 'Pl'};
            app.LastHandPlayedxLabel = uilabel(app.UIFigure);
            app.LastHandPlayedxLabel.Text = {'Last Hand Played:'; 'x'};
            app.Player4Label = uilabel(app.UIFigure);
            app.Player4Label.Text = 'Player 4';
            app.Player2Label = uilabel(app.UIFigure);
            app.Player2Label.Text = 'Player 2';
            app.Player1Label = uilabel(app.UIFigure);
            app.Player1Label.Text = 'Player 1';
            app.Player3Label = uilabel(app.UIFigure);
            app.Player3Label.Text = 'Player 3';
            app.RulesButton = uibutton(app.UIFigure, 'push');
            app.RulesButton.ButtonPushedFcn = createCallbackFcn(app, @RulesButtonPushed, true);
            app.RulesButton.Text = 'Rules';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.JoinGameButton = uibutton(app.UIFigure, 'push');
            app.JoinGameButton.Text = 'Join Game';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.HowManyPlayersEditFieldLabel = uilabel(app.UIFigure);
            app.HowManyPlayersEditFieldLabel.Text = 'How Many Players?';
            app.HowManyPlayersEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.Button_14 = uibutton(app.UIFigure, 'state');
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.UIFigure, 'state');
            app.Button_15.Text = '';
            app.Button_16 = uibutton(app.UIFigure, 'state');
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.UIFigure, 'state');
            app.Button_17.Text = '';
            app.Button_18 = uibutton(app.UIFigure, 'state');
            app.Button_18.Text = '';
            app.Button_19 = uibutton(app.UIFigure, 'state');
            app.Button_19.Text = '';
            app.Button_20 = uibutton(app.UIFigure, 'state');
            app.Button_20.Text = '';
            app.Button_21 = uibutton(app.UIFigure, 'state');
            app.Button_21.Text = '';
            app.Button_22 = uibutton(app.UIFigure, 'state');
            app.Button_22.Text = '';
            app.Button_23 = uibutton(app.UIFigure, 'state');
            app.Button_23.Text = '';
            app.Button_24 = uibutton(app.UIFigure, 'state');
            app.Button_24.Text = '';
            app.Button_25 = uibutton(app.UIFigure, 'state');
            app.Button_25.Text = '';
            app.Button_26 = uibutton(app.UIFigure, 'state');
            app.Button_26.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = President

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end